classdef Probablity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ChoosewhichoperationDropDownLabel  matlab.ui.control.Label
        ChoosewhichoperationDropDown  matlab.ui.control.DropDown
        CalculateButton               matlab.ui.control.Button
        EnterMEditFieldLabel          matlab.ui.control.Label
        EnterMEditField               matlab.ui.control.NumericEditField
        EnterNEditFieldLabel          matlab.ui.control.Label
        EnterNEditField               matlab.ui.control.NumericEditField
        EnterIEditFieldLabel          matlab.ui.control.Label
        EnterIEditField               matlab.ui.control.NumericEditField
        EnterJEditFieldLabel          matlab.ui.control.Label
        EnterJEditField               matlab.ui.control.NumericEditField
        NameoftimefileEditFieldLabel  matlab.ui.control.Label
        NameoftimefileEditField       matlab.ui.control.EditField
        NameofamplitudefileEditFieldLabel  matlab.ui.control.Label
        NameofamplitudefileEditField  matlab.ui.control.EditField
        STOCHASTICPROCESSESCALCULATORLabel  matlab.ui.control.Label
        TimeMeanEditFieldLabel        matlab.ui.control.Label
        TimeMeanEditField             matlab.ui.control.NumericEditField
        ACFEditFieldLabel             matlab.ui.control.Label
        ACFEditField                  matlab.ui.control.NumericEditField
        PowerAverageEditFieldLabel    matlab.ui.control.Label
        PowerAverageEditField         matlab.ui.control.NumericEditField
        TimeACFEditFieldLabel         matlab.ui.control.Label
        TimeACFEditField              matlab.ui.control.NumericEditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            I=app.EnterIEditField.Value;
            J=app.EnterJEditField.Value;
            M=app.EnterMEditField.Value;
            N=app.EnterNEditField.Value;
            time_file=app.NameoftimefileEditField.Value;
            amplitude_file=app.NameofamplitudefileEditField.Value;
            t=readmatrix(time_file);
            x=readmatrix(amplitude_file);
           [Smallest,Largest]=bounds(t);
           FT_S_AC=zeros(height(x),width(x)
           
              for I=1:width(x)
                  for J=1:width(x)
                     for v=1:height(x) 
                         
                                FT_S_AC(I,J)=FT_S_AC(I,J)+(x(v,I)*x(v,J)); %I,J
                      end
                                FT_S_AC(I,J)=FT_S_AC(I,J)/height(x);
                   end
                end
            switch app.ChoosewhichoperationDropDown.Value
                
                case "Plot M sample functions of the ensemble of the process."
                 for i=1:M  
                     subplot(3,3,i);
                     plot(t2,x(i,:));
                     title (sprintf('%dth sample function',i))
                     xlabel('Time');
                     ylabel('Amplitude');
                 end
                case "Calculate and plot the ensemble mean of the process."
                    m_mean = mean(x);
                    plot(t2,m_mean)
                    title('The Enssemble Mean')
                    xlabel('Time');
                    ylabel('Amplitude');
                case "Calculates the time mean of the M sample function of the process."    
                    Summation=sum(x(N,:)); 
                    Time_mean=abs(Summation*Step_Size*(1/Largest-Smallest));
                    app.TimeMeanEditField.Value=Time_mean;
                case "Calculate and plot the statistical auto-correlation function between the I sample and the J sample of the process."
                   S_AC=0;
                   for v=1:length(t)
                       S_AC=S_AC+ (x(v,I)*x(v,J)); %I,J
                   end
                   S_AC=S_AC/height(x);
                   app.ACFEditField.Value=S_AC;
                   surf(FT_S_AC)
                   title("The Statistical Auto-Correlation");
                   xlabel('Time');
                   ylabel('Amplitude');
                case "Calculate the time auto-correlation function of the M sample function of the process."
                    tawo=1;
                    Time_ACC= zeros(1,width(x));
                   for tawo=1:width(x)-1
                       for i=1:width(x) %From 1 to the size of the columns
                           if i+tawo <= width(x)
                              Time_ACC(tawo)=Time_ACC(tawo)+(x(6,i)*x(6,i+tawo)*Step_Size *(1/Largest-Smallest)); %n
                           end
                      end
                   end
                 plot(t,Time_ACC);
                 title('Time Auto-Correlation');
                 xlabel('Time');
                 yabel('Amlitude');
                case "Calculate and plot the power spectral density of the process."
                   FFT_S_AC= fftshift(fft(FT_S_AC))./length(t);
                   FFT_S_AC=abs(FFT_S_AC);
                   z=linspace(-50,50,width(x));
                   plot(z,FFT_S_AC)
                   title('Power Spectral Density');
                   xlabel('Frequency in HZ');
                   ylabel('Amplitude');
                case "Calculate the total average power of the process."
                   total_average_power=FT_S_AC(1,1);
                   app.PowerAverageEditField.Value=total_average_power;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChoosewhichoperationDropDownLabel = uilabel(app.UIFigure);
            app.ChoosewhichoperationDropDownLabel.Text = 'Choose which operation';
            app.ChoosewhichoperationDropDown = uidropdown(app.UIFigure);
            app.ChoosewhichoperationDropDown.Items = {'Plot M sample functions of the ensemble of the process.', 'Calculate and plot the ensemble mean of the process.', 'Calculates the time mean of the M sample function of the process.', 'Calculate and plot the statistical auto-correlation function between the I sample and the J sample of the process.', 'Calculate the time auto-correlation function of the M sample function of the process.', 'Calculate and plot the power spectral density of the process.', 'Calculate the total average power of the process.'};
            app.ChoosewhichoperationDropDown.Value = 'Plot M sample functions of the ensemble of the process.';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EnterMEditFieldLabel = uilabel(app.UIFigure);
            app.EnterMEditFieldLabel.Text = 'Enter M';
            app.EnterMEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterNEditFieldLabel = uilabel(app.UIFigure);
            app.EnterNEditFieldLabel.Text = 'Enter N';
            app.EnterNEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterIEditFieldLabel = uilabel(app.UIFigure);
            app.EnterIEditFieldLabel.Text = 'Enter I';
            app.EnterIEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterJEditFieldLabel = uilabel(app.UIFigure);
            app.EnterJEditFieldLabel.Text = 'Enter J';
            app.EnterJEditField = uieditfield(app.UIFigure, 'numeric');
            app.NameoftimefileEditFieldLabel = uilabel(app.UIFigure);
            app.NameoftimefileEditFieldLabel.Text = 'Name of  time file';
            app.NameoftimefileEditField = uieditfield(app.UIFigure, 'text');
            app.NameofamplitudefileEditFieldLabel = uilabel(app.UIFigure);
            app.NameofamplitudefileEditFieldLabel.Text = 'Name of  amplitude file';
            app.NameofamplitudefileEditField = uieditfield(app.UIFigure, 'text');
            app.STOCHASTICPROCESSESCALCULATORLabel = uilabel(app.UIFigure);
            app.STOCHASTICPROCESSESCALCULATORLabel.Text = 'STOCHASTIC PROCESSES CALCULATOR';
            app.TimeMeanEditFieldLabel = uilabel(app.UIFigure);
            app.TimeMeanEditFieldLabel.Text = 'Time Mean';
            app.TimeMeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.ACFEditFieldLabel = uilabel(app.UIFigure);
            app.ACFEditFieldLabel.Text = 'ACF';
            app.ACFEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerAverageEditFieldLabel = uilabel(app.UIFigure);
            app.PowerAverageEditFieldLabel.Text = 'Power Average';
            app.PowerAverageEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeACFEditFieldLabel = uilabel(app.UIFigure);
            app.TimeACFEditFieldLabel.Text = 'Time ACF';
            app.TimeACFEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Probablity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end